classdef Analysis_environment < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Analysis_Environment            matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        LoaddataTab                     matlab.ui.container.Tab
        ReadrawdataButton               matlab.ui.control.Button
        filetype                        matlab.ui.control.DropDown
        DatacolumnsEditFieldLabel       matlab.ui.control.Label
        DatacolumnsEditField            matlab.ui.control.EditField
        experimentnameEditFieldLabel    matlab.ui.control.Label
        experimentnameEditField         matlab.ui.control.EditField
        measurementnameEditFieldLabel   matlab.ui.control.Label
        measurementnameEditField        matlab.ui.control.EditField
        measurementdateEditFieldLabel   matlab.ui.control.Label
        measurementdateEditField        matlab.ui.control.EditField
        FiletypeLabel                   matlab.ui.control.Label
        MEALayoutEditFieldLabel         matlab.ui.control.Label
        MEALayoutEditField              matlab.ui.control.EditField
        DatatypeButtonGroup             matlab.ui.container.ButtonGroup
        MEAButton                       matlab.ui.control.RadioButton
        OtherButton                     matlab.ui.control.RadioButton
        MEAelectrodesEditFieldLabel     matlab.ui.control.Label
        MEAelectrodesEditField          matlab.ui.control.EditField
        ProcessdatafilteringPanel       matlab.ui.container.Panel
        FilterdataButton                matlab.ui.control.Button
        PeakHandlingTab                 matlab.ui.container.Tab
        ChoosepeakstohandleButtonGroup  matlab.ui.container.ButtonGroup
        highpeaksButton                 matlab.ui.control.RadioButton
        lowpeaksButton                  matlab.ui.control.RadioButton
        bothButton                      matlab.ui.control.RadioButton
        filenumsPeakHandlingEditField_Label  matlab.ui.control.Label
        filenumsPeakHandlingEditField   matlab.ui.control.EditField
        columnsPeakHandlingEditField_Label  matlab.ui.control.Label
        columnsPeakHandlingEditField    matlab.ui.control.EditField
        RulesandfindpeaksPanel          matlab.ui.container.Panel
        TimerangeEditFieldLabel         matlab.ui.control.Label
        TimerangeEditField              matlab.ui.control.EditField
        find_local_maximaButton         matlab.ui.control.Button
        find_peaks_with_rulesButton     matlab.ui.control.Button
        MaxBPMLabel                     matlab.ui.control.Label
        MaxBPMEditField                 matlab.ui.control.EditField
        MinPeakDistancemsLabel          matlab.ui.control.Label
        MinPeakDistancemsEditField      matlab.ui.control.EditField
        MinPeakAmplitudeabsLabel        matlab.ui.control.Label
        MinPeakAmplitudeabsEditField    matlab.ui.control.EditField
        DeletepeaksPanel                matlab.ui.container.Panel
        delete_all_peaksButton          matlab.ui.control.Button
        delete_peaks_with_peaknumberButton  matlab.ui.control.Button
        peaknumbersEditField            matlab.ui.control.EditField
        peaknumbersEditField_Label      matlab.ui.control.Label
        delete_peaks_with_timeButton    matlab.ui.control.Button
        delete_peaks_with_time_Label    matlab.ui.control.Label
        timerangeEditField              matlab.ui.control.EditField
        delete_peaks_belowButton        matlab.ui.control.Button
        delete_peaks_below_Label        matlab.ui.control.Label
        minAmplitudeEditField           matlab.ui.control.EditField
        delete_peaks_aboveButton        matlab.ui.control.Button
        delete_peaks_above_Label        matlab.ui.control.Label
        maxAmplitudeEditField           matlab.ui.control.EditField
        delete_first_peakButton         matlab.ui.control.Button
        delete_last_peakButton          matlab.ui.control.Button
        DefinepeaksPanel                matlab.ui.container.Panel
        add_peaks_from_otherButton      matlab.ui.control.Button
        set_main_peaksButton            matlab.ui.control.Button
        define_other_peaks_from_mainButton  matlab.ui.control.Button
        CheckpeaksPanel                 matlab.ui.container.Panel
        check_peak_distances_from_edgesButton  matlab.ui.control.Button
        AddpeaksPanel                   matlab.ui.container.Panel
        addpeaks_in_time_rangeButton    matlab.ui.control.Button
        AnalysisTab                     matlab.ui.container.Tab
        PoistolaariTab                  matlab.ui.container.Tab
        SaveData_BPMButton              matlab.ui.control.Button
        SaveBPM_summaryButton           matlab.ui.control.Button
        Save_closeall_plot_nextButton   matlab.ui.control.Button
        SaveDataInfoButton              matlab.ui.control.Button
        TextArea_6                      matlab.ui.control.TextArea
        TextArea_7                      matlab.ui.control.TextArea
        EditdataPanel                   matlab.ui.container.Panel
        SignalTypeDropDown              matlab.ui.control.DropDown
        set_signal_typeButton           matlab.ui.control.Button
        turn_signalButton               matlab.ui.control.Button
        GeneralPanel                    matlab.ui.container.Panel
        ChoosedataforplottingPanel      matlab.ui.container.Panel
        IcludepeaknumbersinplotCheckBox  matlab.ui.control.CheckBox
        PlotdatacolumnstosameCheckBox   matlab.ui.control.CheckBox
        filenumsEditField_Label         matlab.ui.control.Label
        filenumsEditField               matlab.ui.control.EditField
        columnsEditField_Label          matlab.ui.control.Label
        columnsEditField                matlab.ui.control.EditField
        PlotdatafilestosameCheckBox     matlab.ui.control.CheckBox
        SaveloadandclearPanel           matlab.ui.container.Panel
        ChooseFolderButton              matlab.ui.control.Button
        SavingfolderLabel               matlab.ui.control.Label
        SaveDataButton                  matlab.ui.control.Button
        TextArea_4                      matlab.ui.control.TextArea
        SaveButton                      matlab.ui.control.Button
        clearallexceptButton            matlab.ui.control.Button
        notremoveEditField              matlab.ui.control.EditField
        LoadmatfilesButton              matlab.ui.control.Button
        PlotPanel                       matlab.ui.container.Panel
        PlotRAWButton                   matlab.ui.control.Button
        PlotDataButton                  matlab.ui.control.Button
        CloseallButton                  matlab.ui.control.Button
        PlotsignalupsidedownButton      matlab.ui.control.Button
        plot_every10th_signal           matlab.ui.control.Button
        plot_random_file                matlab.ui.control.Button
        amount_of_random_files          matlab.ui.control.NumericEditField
        AmountofplotsLabel              matlab.ui.control.Label
        plot_next_in_filenum            matlab.ui.control.Button
        ClosethesefiguresButton         matlab.ui.control.Button
        SizeButtonGroup                 matlab.ui.container.ButtonGroup
        DefaultsizeButton               matlab.ui.control.RadioButton
        FullscreenButton                matlab.ui.control.RadioButton
        HalfscreenButton                matlab.ui.control.RadioButton
        FigurenumberstocloseLabel       matlab.ui.control.Label
        FigurenumberstocloseEditField   matlab.ui.control.EditField
        CopyFiguretoclipboardButton     matlab.ui.control.Button
        UpdatefilenumsfromworkspaceButton  matlab.ui.control.Button
        PlotButton                      matlab.ui.control.Button
        RawDataCheckBox                 matlab.ui.control.CheckBox
        MeanDataPeakCheckBox            matlab.ui.control.CheckBox
        everyDataPeaksCheckBox          matlab.ui.control.CheckBox
        ListfilenamesoffilenumsButton   matlab.ui.control.Button
        PrintsfilenamesonworkspaceLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        % when reading from panels
        % str2num is used as can be in vector format 2:5 --> str2double does not work!
        filenums % filenumber to handle
        datacolumns % datacolumns to handle
        whichpeak % high, low, or both peaks
        maxBPMinpeakfind % max BPM value used in peak finding (affecting minimum peak distance)
        minAmptofind % minimum (absolute) value for peak finding
    end
    
    methods (Access = private)
        
        function [filenum,cols,which_peaks_handled] = read_values_from_app(app)
            % narginchk(0,0)
            nargoutchk(0,3)
            % now reading these filenum and cols values from peak handling parameters
            filenum = str2num(app.filenumsPeakHandlingEditField.Value);
            cols = str2num(app.columnsPeakHandlingEditField.Value);
            % filenum = app.filenums;
            % cols = app.datacolumns;
            switch app.whichpeak
                case 'low peaks'
                    which_peaks_handled = 0;
                    disp('Handling low peaks')
                case 'high peaks'
                    which_peaks_handled = 1;
                    disp('Handling high peaks')
                case 'both'
                    which_peaks_handled = 2;
                    disp('Handling both high and low peaks')
            end
        end
        function [figure_size] = read_figure_size(app)
            nargoutchk(0,1)
            figure_size = 'default';
            switch app.SizeButtonGroup.SelectedObject.Text
                case 'Full screen'
                    figure_size = 'full';
                case 'Half screen'
                    figure_size = 'half';
            end
        end
        function hfig = create_figure(app)
            nargoutchk(0,1)
            switch app.SizeButtonGroup.SelectedObject.Text
                case  'Default size'
                    hfig = figure;
                case 'Full screen'
                    hfig = figure;
                    hfig.WindowState = 'maximized';
                case 'Half screen'
                    hfig = figure;
                    org_size = hfig.OuterPosition;
                    % because of some delay with WindowState = 'maximed'
                    % first wait enough that figure is full size
                    hfig.WindowState = 'maximized';
                    counter=0;
                    pause_time = 0.01;
                    max_waiting_time=0.5; % sec
                    while (hfig.OuterPosition <= org_size*1.02)
                        % wait until figure size is maximized
                        % or max_waiting_time sec
                        counter = counter + 1;
                        if (counter > max_waiting_time/pause_time)
                            disp('break')
                            break
                        end
                        pause(pause_time)
                    end
                    full_size_fig_parameters=hfig.OuterPosition;
                    % reducing size to half
                    set(hfig,'OuterPosition',[full_size_fig_parameters(1) + ...
                        round(full_size_fig_parameters(3)/3), ...
                        full_size_fig_parameters(2)+round(full_size_fig_parameters(4)/3), ...
                        round(full_size_fig_parameters(3)/2),round(full_size_fig_parameters(4)/2)]);
                    pause(0.1)
            end
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % str2num is used as can be in vector format 2:5 --> str2double does not work!
            app.filenums = str2num(app.filenumsEditField.Value);
            try
                temp = evalin('base','DataInfo.datacol_numbers');
                disp('DataInfo.datacol_numbers found on workspace, setting datacols based on that')
                datacols = 1:length(temp);
            catch
                datacols = 1;
            end
            app.columnsEditField.Value = num2str(datacols);
            app.datacolumns = datacols;
            app.columnsPeakHandlingEditField.Value = num2str(datacols);
            app.filenumsPeakHandlingEditField.Value = app.filenumsEditField.Value;
            % columnsEditFieldValueChanged(app, event) % idea was to updats datacolumns, no event on startup!
            
            app.whichpeak = app.ChoosepeakstohandleButtonGroup.SelectedObject.Text;
            app.maxBPMinpeakfind = str2num(app.MaxBPMEditField.Value);
            app.MinPeakDistancemsEditField.Value = num2str(60/app.maxBPMinpeakfind*1e3);
            try
                minpeakval = evalin('base','DataInfo.Rule.MinPeakValue');
            catch
                disp('No DataInfo.Rule.MinPeakValue found on workspace')
                minpeakval = 2.5e-5;
                disp(['Setting default value to min peak value: ',num2str(minpeakval)])
            end
            app.MinPeakAmplitudeabsEditField.Value = num2str(minpeakval);
            app.minAmplitudeEditField.Value = num2str(minpeakval);
            app.maxAmplitudeEditField.Value = num2str(minpeakval);
            app.minAmptofind = minpeakval;
        end

        % Button pushed function: PlotDataButton
        function PlotDataButtonPushed(app, event)
            % disp('Plotting data defined in filenums and columns')
            filenum = app.filenums;
            cols = app.datacolumns;
            plotting_peak_numbers = app.IcludepeaknumbersinplotCheckBox.Value;
            plotting_data_columns_to_same = app.PlotdatacolumnstosameCheckBox.Value;
            plotting_data_files_to_same = app.PlotdatafilestosameCheckBox.Value;
            if length(filenum) == 1
                % if only one filenum is chosen, make plotting_data_files_to_same = 0,
                % no matter what is app.PlotdatafilestosameCheckBox.Value;
                plotting_data_files_to_same = 0;
            end
            try
                dat = evalin('base','Data');
            catch
                error('No Data found')
            end
            try
                datbpm = evalin('base','Data_BPM');
                bpm_found = 1;
            catch
                bpm_found = 0;
                disp('No peaks Data_BPM found, plotting only raw data')
            end
            try
                datainfo = evalin('base','DataInfo');
            catch
                error('No DataInfo found')
            end
            
            if plotting_data_files_to_same == 0
                hfigs=[];
                for kk = 1:length(filenum)
                    hfigs(end+1,1) = create_figure(app);
                    % pause(0.2)
                    if bpm_found == 1 % && plotting_peak_numbers == 1
                        PLOT_DataWithPeaks_no_own_fig(dat,datbpm,filenum(kk),cols,plotting_peak_numbers,plotting_data_columns_to_same)
                    else
                        PLOT_DataOnly_no_own_figure(dat,filenum(kk),cols,plotting_data_columns_to_same);
                    end
                    zoom on
                end
                pause(0.2)
                for kk=length(hfigs):-1:1
                    figure(hfigs(kk,1))
                end
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            else % plot all filenums to same figure
                [fig_parameters] = cal_subfig_parameters(cols);
                sub_fig_rows=fig_parameters(1);
                sub_fig_cols=fig_parameters(2);
                extra_in_x_axis = 0.2; % for peak numbers
                
                if plotting_data_columns_to_same == 0
                    data_with_legend = [];
                    legs = cell(length(filenum),length(cols));
                else % all plots to one figure without subplots
                    legs = [];
                    data_with_legend = [];
                end
                for kk = 1:length(filenum)
                    file_index = filenum(kk);
                    if kk == 1
                        % figure size based on chosen
                        hfig = create_figure(app);
                        % pause(0.2)
                    end
                    % assuming data length (#rows) to be same in each data column in one file index
                    time = 0:1/datainfo.framerate(file_index):...
                        (length(dat{file_index}.data(:,1))-1)/datainfo.framerate(file_index);
                    for pp = 1:length(cols)
                        col_index = cols(pp);
                        try
                            leg_start = ['File#', num2str(file_index),'(t=',...
                                num2str(round(datainfo.measurement_time.time_sec(file_index)/3600,1)),'h)'];
                        catch
                            leg_start = ['File#', num2str(file_index)];
                        end
                        if plotting_data_columns_to_same == 0 || length(cols) < 2
                            % if all data columns are plotted to same figure --> no subplots needed
                            % otherwise, plot subplots based on amount of chosen datacolumns
                            subplot(sub_fig_rows,sub_fig_cols,pp)
                            if isfield(datainfo,'MEA_electrode_numbers')
                                title(['El#',num2str(datainfo.MEA_electrode_numbers(col_index))]);
                            else
                                title(['Col#',num2str(datainfo.datacol_numbers(col_index))]);
                            end
                            data_with_legend(kk,pp) = plot_data_with_linestyle(dat{file_index}.data(:,col_index),time);
                            % legend can be shorter as subplot title tells data column info
                            legs{kk,pp} = [leg_start];% ,', file_index=',num2str(file_index)];
                        else % no subplots: include el/col# in legend
                            data_with_legend(end+1) = plot_data_with_linestyle(dat{file_index}.data(:,col_index),time);
                            if isfield(datainfo,'MEA_electrode_numbers')
                                legs{end+1} = [leg_start,': El#',num2str(datainfo.MEA_electrode_numbers(col_index))];
                            else
                                legs{end+1} = [leg_start,': Col#',num2str(datainfo.datacol_numbers(col_index))];
                            end
                            
                        end
                        hold all
                        
                        
                        if bpm_found == 1 % plotting possible peaks
                            try
                                peak_times = time(datbpm{file_index,1}.peak_locations_high{col_index});
                                peak_values = datbpm{file_index,1}.peak_values_high{col_index};
                                plot(peak_times, peak_values,'ro')
                                highpeak_amount = length(peak_values);
                                % if include peak numbers in plot
                                if plotting_peak_numbers == 1
                                    text(peak_times+extra_in_x_axis,peak_values,...
                                        num2str((1:numel(peak_times))'))
                                end
                            catch %  ('no high peaks found')
                                highpeak_amount = 0;
                            end
                            try
                                peak_times = time(datbpm{file_index,1}.peak_locations_low{col_index});
                                peak_values = datbpm{file_index,1}.peak_values_low{col_index};
                                plot(peak_times, peak_values,'x','color',[0 .5 0.])
                                lowpeak_amount = length(peak_values);
                                % if include peak numbers in plot
                                if plotting_peak_numbers == 1
                                    text(peak_times+extra_in_x_axis,peak_values,...
                                        num2str((1:numel(peak_times))'))
                                end
                            catch %  ('no low peaks found')
                                lowpeak_amount = 0;
                            end
                        end % end: % plotting possible peaks
                        axis tight
                    end % end pp = 1:length(cols)
                end % end for kk = 1:length(filenum)
                sgtitle([datainfo.experiment_name, ' - ', datainfo.measurement_name],...
                    'interpreter','none','fontsize',12)
                if plotting_data_columns_to_same == 0
                    % each subplots with own legends
                    for pp = 1:length(cols)
                        subplot(sub_fig_rows,sub_fig_cols,pp)
                        if length(legs) > 3 % legend text in two columns if more than 3 data
                            legend(data_with_legend(:,pp), legs(:,pp), ...
                                'location', 'best','interpreter','none','NumColumns',2)
                        else
                            legend(data_with_legend(:,pp), legs(:,pp), ...
                                'location', 'best','interpreter','none')
                        end
                    end
                else
                    if length(legs) > 3 % legend text in two columns if more than 3 data
                        legend(data_with_legend, legs, 'location', 'best',...
                            'interpreter','none','NumColumns',2)
                    else
                        legend(data_with_legend, legs, 'location', 'best',...
                            'interpreter','none')
                    end
                    
                end
                zoom on
            end % end_: plot all filenums to same figure
            
        end

        % Button pushed function: CloseallButton
        function CloseallButtonPushed(app, event)
            disp('Closing all figures')
            close all
        end

        % Button pushed function: SaveData_BPMButton
        function SaveData_BPMButtonPushed(app, event)
            disp('Saving Data_BPM')
            DataInfo = evalin('base','DataInfo');
            Data_BPM = evalin('base','Data_BPM');
            savename = ['Data_BPM_',DataInfo.experiment_name,'_',DataInfo.measurement_name];
            savefolder = [app.SavingfolderLabel.Text,'\'];
            if strcmp(savefolder, 'Saving folder')
                savefolder = [];
            end
            save_data_files(savefolder)
            % save_DataBPM_ and_DataInfo(Data_BPM, DataInfo, savename, savefolder)
            % function save_DataBPM(Data_BPM, DataInfo, savefolder, savename)
            save_DataBPM(Data_BPM, DataInfo, savefolder, savename)
        end

        % Button pushed function: SaveDataButton
        function SaveDataButtonPushed(app, event)
            disp('Saving all: including Data (+other "big variables")')
            warning('THIS MIGHT take for a while')
            savefolder = [app.SavingfolderLabel.Text];
            if strcmp(savefolder, 'Saving folder')
                savefolder = [];
            end
            newsavefolder = save_data_files(savefolder,1,1,1);
            % function save_data_files(savefolder, saving_DataPeaks_mean, saving_DataPeaks,saving_raw_data,..
            disp(['Saved in folder: ',newsavefolder])
            if isempty(savefolder)
                app.SavingfolderLabel.Text=newsavefolder;
            end
            
        end

        % Button pushed function: ChooseFolderButton
        function ChooseFolderButtonPushed(app, event)
            savefolder = [app.SavingfolderLabel.Text];
            % Calls 'uigetdir' to obtain the directory location from the user
            if strcmp(savefolder, 'Saving folder')
                path=uigetdir([],'Choose saving directory');
            else
                path=uigetdir(savefolder,'Choose saving directory');
            end
            
            app.Analysis_Environment.Visible = 'off';     % Toggles figure visible.  These two lines of code work-around an issue whether the figure is sent to the background.
            app.Analysis_Environment.Visible = 'on';
            app.SavingfolderLabel.Text=path;  % Sets the label text to be the selected path
            
        end

        % Button pushed function: SaveBPM_summaryButton
        function SaveData_BPM_summaryButtonPushed(app, event)
            disp('Saving summary of Data_BPM')
            try
                DataInfo = evalin('base','DataInfo');
                Data_BPM_summary = evalin('base','Data_BPM_summary');
                savename = ['Data_BPM_summary_',DataInfo.experiment_name,'_',DataInfo.measurement_name];
                savefolder = [app.SavingfolderLabel.Text,'\'];
                if strcmp(savefolder, 'Saving folder')
                    savefolder = [];
                end
                save_data_files(savefolder)
                % save_DataBPMsummary_and_DataInfo(Data_BPM_summary, DataInfo, savefolder, savename)
                % function save_DataBPMsummary(Data_BPM_summary, DataInfo, savefolder, savename)
                % save_DataBPMsummary(Data_BPM_summary, DataInfo, savefolder, savename)
            catch
                warning('No Data_BPM_summary on workspace! -> not saved')
            end
        end

        % Callback function
        function set_signal_typeButtonPushed(app, event)
            disp('Settings signal type')
            dat_info = evalin('base','DataInfo');
            filenum = app.filenums;
            cols = app.datacolumns;
            dat_info = set_signal_type(dat_info,filenum,cols,app.SignalTypeDropDown.Value);
            % function [DataInfo] = set_signal_type(DataInfo,file_index,datacolumns,signal_type)
            assignin('base','DataInfo',dat_info)
        end

        % Callback function
        function turn_signalButtonPushed(app, event)
            disp('Turning signal upside down')
            dat = evalin('base','Data');
            filenum = app.filenums;
            cols = app.datacolumns;
            try
                datbpm = evalin('base','Data_BPM');
                % function [Data,Data_BPM, DataInfo] = turn_signal_upside_down(Data,index,datacolumns);
                [dat, datbpm, datinfo] = turn_signal_and_Data_BPM(dat, datbpm,filenum,cols);
                assignin('base','Data_BPM',datbpm)
                assignin('base','DataInfo',datinfo)
            catch
                dat = turn_signal_upside_down(dat,filenum,cols);
            end
            assignin('base','Data',dat)
        end

        % Button pushed function: PlotsignalupsidedownButton
        function PlotsignalupsidedownButtonPushed(app, event)
            disp('Plotting signal upside down')
            filenum = app.filenums;
            cols = app.datacolumns;
            try
                dat = evalin('base','Data');
            catch
                error('No Data found')
            end
            try
                datbpm = evalin('base','Data_BPM');
                bpm_found = 1;
            catch
                bpm_found = 0;
                disp('No peaks Data_BPM found, plotting only raw data')
            end
            %  PLOT_signal_upside_down(Data, Data_BPM, file_index,datacolumns)
            if bpm_found == 1
                PLOT_signal_upside_down(dat, datbpm,filenum,cols);
            else
                PLOT_signal_upside_down(dat, [],filenum,cols);
            end
        end

        % Button pushed function: PlotRAWButton
        function PlotRAWButtonPushed(app, event)
            disp('Plotting raw data defined in filenums and columns')
            try
                dat = evalin('base','Data');
            catch
                error('No Data found')
            end

            try
                datainfo = evalin('base','DataInfo');
            catch
                error('No DataInfo found')
            end            
            filenum = app.filenums;
            cols = app.datacolumns;
            plotting_data_columns_to_same = app.PlotdatacolumnstosameCheckBox.Value;
            plotting_data_files_to_same = app.PlotdatafilestosameCheckBox.Value;
            hfigs=[];
            if plotting_data_files_to_same == 0
                for kk = 1:length(filenum)
                    hfigs(end+1,1) = create_figure(app);
                    % pause(0.2)
                    PLOT_DataOnly_no_own_figure(dat,filenum(kk),cols,plotting_data_columns_to_same);
                    zoom on
                end
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            else % plot all filenums to same figure
                [fig_parameters] = cal_subfig_parameters(cols);
                sub_fig_rows=fig_parameters(1);
                sub_fig_cols=fig_parameters(2);
                if plotting_data_columns_to_same == 0
                    data_with_legend = [];
                    legs = cell(length(filenum),length(cols));
                else % all plots to one figure without subplots
                    legs = [];
                    data_with_legend = [];
                end
                for kk = 1:length(filenum)
                    file_index = filenum(kk);
                    if kk == 1
                        hfig = create_figure(app);
                    end
                    % assuming data length (#rows) to be same in each data column in one file index
                    time = 0:1/datainfo.framerate(file_index):...
                        (length(dat{file_index}.data(:,1))-1)/datainfo.framerate(file_index);
                    for pp = 1:length(cols)
                        col_index = cols(pp);
                        try
                            leg_start = ['File#', num2str(file_index),'(t=',...
                                num2str(round(datainfo.measurement_time.time_sec(file_index)/3600,1)),'h)'];
                        catch
                            leg_start = ['File#', num2str(file_index)];
                        end
                        if plotting_data_columns_to_same == 0 || length(cols) < 2
                            % if all data columns are plotted to same figure --> no subplots needed
                            % otherwise, plot subplots based on amount of chosen datacolumns
                            subplot(sub_fig_rows,sub_fig_cols,pp)
                            if isfield(datainfo,'MEA_electrode_numbers')
                                title(['El#',num2str(datainfo.MEA_electrode_numbers(col_index))]);
                            else
                                title(['Col#',num2str(datainfo.datacol_numbers(col_index))]);
                            end
                            data_with_legend(kk,pp) = plot_data_with_linestyle(dat{file_index}.data(:,col_index),time);
                            % legend can be shorter as subplot title tells data column info
                            legs{kk,pp} = [leg_start];% ,', file_index=',num2str(file_index)];
                        else % no subplots: include el/col# in legend
                            data_with_legend(end+1) = plot_data_with_linestyle(dat{file_index}.data(:,col_index),time);
                            if isfield(datainfo,'MEA_electrode_numbers')
                                legs{end+1} = [leg_start,': El#',num2str(datainfo.MEA_electrode_numbers(col_index))];
                            else
                                legs{end+1} = [leg_start,': Col#',num2str(datainfo.datacol_numbers(col_index))];
                            end
                            
                        end
                        hold all
                        
                        axis tight
                    end % end pp = 1:length(cols)
                end % end for kk = 1:length(filenum)
                sgtitle([datainfo.experiment_name, ' - ', datainfo.measurement_name],...
                    'interpreter','none','fontsize',12)
                if plotting_data_columns_to_same == 0
                    % each subplots with own legends
                    for pp = 1:length(cols)
                        subplot(sub_fig_rows,sub_fig_cols,pp)
                        if length(legs) > 3 % legend text in two columns if more than 3 data
                            legend(data_with_legend(:,pp), legs(:,pp), ...
                                'location', 'best','interpreter','none','NumColumns',2)
                        else
                            legend(data_with_legend(:,pp), legs(:,pp), ...
                                'location', 'best','interpreter','none')
                        end
                    end
                else
                    if length(legs) > 3 % legend text in two columns if more than 3 data
                        legend(data_with_legend, legs, 'location', 'best',...
                            'interpreter','none','NumColumns',2)
                    else
                        legend(data_with_legend, legs, 'location', 'best',...
                            'interpreter','none')
                    end
                    
                end
                zoom on
            end % end_: plot all filenums to same figure
            zoom on
            
            pause(0.2)
            for kk=length(hfigs):-1:1
                figure(hfigs(kk,1))
            end
        end

        % Button pushed function: SaveDataInfoButton
        function SaveDataInfoButtonPushed(app, event)
            disp('Saving DataInfo')
            DataInfo = evalin('base','DataInfo');
            savename = ['DataInfo_',DataInfo.experiment_name,'_',DataInfo.measurement_name];
            savefolder = [app.SavingfolderLabel.Text];% ,'\'];
            if strcmp(savefolder, 'Saving folder')
                savefolder = [];
            end
            save_data_files(savefolder)
            % function save_data_files(savefolder, saving_DataPeaks_mean, saving_DataPeaks,saving_raw_data,...
        end

        % Button pushed function: Save_closeall_plot_nextButton
        function Save_closeall_plot_nextButtonPushed(app, event)
            disp('Saving DataInfo Data_BPM and Data_BPM_summary separately')
            savefolder = [app.SavingfolderLabel.Text,'\'];
            if strcmp(savefolder, 'Saving folder\')
                savefolder = [pwd,'\'];
            end
            DataInfo = evalin('base','DataInfo');
            savename = ['DataInfo_',DataInfo.experiment_name,'_',DataInfo.measurement_name];
            disp('Saving DataInfo variable')
            save([savefolder, savename] ,'DataInfo')
            disp(['... saved'])
            try
                Data_BPM = evalin('base','Data_BPM');
                savename = ['Data_BPM_',DataInfo.experiment_name,'_',DataInfo.measurement_name];
                disp('Saving Data_BPM variable')
                save([savefolder, savename] ,'Data_BPM')
                disp(['... saved'])
            catch
                fprintf(2,'\nNo Data_BPM on workspace! -> not saved\n')
                %warning('No Data_BPM on workspace! -> not saved')
            end
            try
                Data_BPM_summary = evalin('base','Data_BPM_summary');
                savename = ['Data_BPM_summary_',DataInfo.experiment_name,'_',DataInfo.measurement_name];
                disp('Saving Data_BPM_summary variable')
                save([savefolder, savename] ,'Data_BPM_summary')
                disp(['... saved'])
            catch
                fprintf(2,'\nNo Data_BPM_summary on workspace! -> not saved\n')
                %                 warning('No Data_BPM_summary on workspace! -> not saved')
            end
            disp(['In folder: ',savefolder])
            %%% CLOSING FIGS
            disp('Closing all figures'),  close all
            %%% PLOTTING NEXT --> based on current_filenum
            try
                current_filenum =  evalin('base','current_filenum');
            catch
                current_filenum =  1;
                % assigning('base','current_filenum', current_filenum);
            end
            next_num = current_filenum+1;
            evalin('base', ['current_filenum = ', num2str(next_num), ';'])
            
            disp('Plotting data defined current_filenum in workspace')
            dat = evalin('base','Data');
            datbpm = evalin('base','Data_BPM');
            filenum=next_num; % str2num(app.filenumsEditField.Value);
            cols = app.datacolumns;
            plotting_peak_numbers = app.IcludepeaknumbersinplotCheckBox.Value;
            plotting_data_columns_to_same = app.PlotdatacolumnstosameCheckBox.Value;
            hfigs=[];
            for kk = 1:length(filenum)
                hfigs(end+1,1) = create_figure(app);
                PLOT_DataWithPeaks_no_own_fig(dat,datbpm,filenum(kk),cols,plotting_peak_numbers,plotting_data_columns_to_same);
                %                 hf = PLOT_DataWithPeaks(dat,datbpm,filenum(kk),cols,plotting_peak_numbers,plotting_data_columns_to_same);
                %                 hfigs(end+1,1) = hf;
                zoom on
            end
            pause(0.2)
            for kk=length(hfigs):-1:1
                figure(hfigs(kk,1))
            end
            
            
        end

        % Value changed function: columnsEditField
        function columnsEditFieldValueChanged(app, event)
            % str2num is used as can be in vector format 2:5 --> str2double does not work!
            app.datacolumns = str2num(app.columnsEditField.Value);
            app.columnsPeakHandlingEditField.Value = app.columnsEditField.Value;
            
        end

        % Value changed function: filenumsEditField
        function filenumsEditFieldValueChanged(app, event)
            app.filenums = str2num(app.filenumsEditField.Value);
            app.filenumsPeakHandlingEditField.Value = app.filenumsEditField.Value;
        end

        % Value changing function: columnsEditField
        function columnsEditFieldValueChanging(app, event)
            app.datacolumns = str2num(event.Value);
            app.columnsPeakHandlingEditField.Value = event.Value;
        end

        % Value changing function: filenumsEditField
        function filenumsEditFieldValueChanging(app, event)
            app.filenums = str2num(event.Value);
            app.filenumsPeakHandlingEditField.Value = event.Value;
            
        end

        % Button pushed function: ReadrawdataButton
        function ReadrawdataButtonPushed(app, event)
            exp_name = app.experimentnameEditField.Value;
            if isempty(exp_name)
                exp_name = 'test'; % set default
            end
            meas_name = app.measurementnameEditField.Value;
            if isempty(meas_name)
                meas_name = 'mea21001a'; % set default
            end
            meas_date = app.measurementdateEditField.Value;
            if isempty(meas_date)
                meas_date = '2020_09_15'; % set default
            end
            if  strcmp(app.DatatypeButtonGroup.SelectedObject.Text,'MEA')
                mea_layout_name = app.MEALayoutEditField.Value;
                if isempty(mea_layout_name)
                    mea_layout_name = 'MEA_64_electrode_layout.txt'; % default MEA layout
                end
                manually_chosen_mea_electrodes = str2num(app.MEAelectrodesEditField.Value);
                [Data, DataInfo ] = load_raw_mea_data_to_Data_and_DataInfo(exp_name, meas_name, meas_date, app.filetype.Value, mea_layout_name, [], manually_chosen_mea_electrodes);
                % function [Data, DataInfo] = load_raw_mea_data_to_Data_and_DataInfo(exp_name, meas_name, meas_date, file_type, mea_layout_name, clear_workspace, manually_chosen_mea_electrodes)
                [DataInfo.Rule] = set_default_filetype_rules_for_peak_finding(DataInfo.framerate(1), app.DatatypeButtonGroup.SelectedObject.Text);
                disp("TODO: Testaa .abf MEA data load toimii")
            else % non-MEA data
                warning("TODO: load non-mea data")
                datacolumns = app.DatacolumnsEditField.Value;
                if isempty(datacolumns)
                    disp("TODO: valitaan kaikki jos ei valittu sarakkeita")
                end
                
            end
            try
                assignin('base','Data',Data)
                assignin('base','DataInfo',DataInfo)
            catch
            end
        end

        % Button pushed function: find_peaks_with_rulesButton
        function find_peaks_with_rulesButtonPushed(app, event)
            id = 'signal:findpeaks:largeMinPeakHeight'; warning('off',id), clear id
            max_BPM = app.maxBPMinpeakfind;
            min_dist_ms = 60/max_BPM*1e3;
            min_amplitude = app.minAmptofind;
            
            dat = evalin('base','Data');
            try
                datbpm = evalin('base','Data_BPM');
            catch
                datbpm = [];
                disp('no Data_BPM on workspace')
            end
            datinfo = evalin('base','DataInfo');
            if max_BPM ~= datinfo.Rule.MaxBPM
                [datinfo] = update_max_bpm_rule(max_BPM);
                disp('Peak find rule related to minimum peak distance updated')
            end
            if min_amplitude ~= datinfo.Rule.MinPeakValue
                datinfo.Rule.MinPeakValue = min_amplitude;
                disp('Peak find rule related to minimum amplitude updated')
            end
            
            
            [filenums,cols,which_peaks_handled] = read_values_from_app(app);
            data_multiply = 1; % = 1 for high peaks, default, will be changed if needed
            disp([10,'%%%%%%%%%%%%%%',10,'Finding peaks with following rules:'])
            disp(['Min peak distance (ms) = ',num2str(min_dist_ms),10,'Min Amplitude (abs) = ',num2str(min_amplitude)])
            % function [Data_BPM] = find_peaks_in_loop(Data, DataInfo, Rule,filenumbers, datacolumns, data_multiply)
            % Rule should have: .FrameRate .MaxBPM .MinPeakValue
            if which_peaks_handled == 1
                % finding only high peaks
                datbpm = find_peaks_in_loop(dat, datinfo, datinfo.Rule, filenums, cols, data_multiply); % 1 = high peaks
            else % finding only low peaks or both peaks
                % finding low peaks first
                datbpm_neg = find_peaks_in_loop(dat, datinfo, datinfo.Rule, filenums, cols, -data_multiply); % -1 = low peaks
                f=fieldnames(datbpm_neg{filenums(1)});
                for pp = 1:length(filenums)
                    file_index = filenums(pp);
                    for hh=1:length(cols)
                        col_index = cols(hh);
                        for ii = 1:length(f)
                            datbpm{file_index,1}.(f{ii})(col_index,1) = datbpm_neg{file_index,1}.(f{ii})(col_index,1);
                        end
                    end
                end
                
                if which_peaks_handled == 2
                    % finding also high peaks
                    assignin('base','Data_BPM',datbpm) % updating found low peaks to workspace
                    datbpm = find_peaks_in_loop(dat, datinfo, datinfo.Rule, filenums, cols, 1); % 1 = high peaks
                end
            end
            % Update current bpm
            % function Data_BPM = update_Data_BPM_single_file(filenums, datacols, using_high_peaks,DataInfo, Data_BPM)
            if which_peaks_handled == 0 % update wit low peak
                datbpm = update_Data_BPM_single_file(filenums, cols, 0,datinfo,datbpm);
            elseif which_peaks_handled == 1 % update wit high peak
                datbpm = update_Data_BPM_single_file(filenums, cols, 1,datinfo,datbpm);
            elseif which_peaks_handled == 2 % check which high or low peak used
                datbpm = update_Data_BPM_single_file(filenums, cols, [],datinfo,datbpm);
            end
            assignin('base','Data_BPM',datbpm) % updating peaks to workspace
            assignin('base','DataInfo',datinfo) % updating DataInfo - tarvitaanko?
            
        end

        % Button pushed function: delete_all_peaksButton
        function delete_all_peaksButtonPushed(app, event)
            disp('deleting peaks')
            datbpm = evalin('base','Data_BPM');
            datinfo = evalin('base','DataInfo');
            filenum = str2num(app.filenumsPeakHandlingEditField.Value);
            cols = str2num(app.columnsPeakHandlingEditField.Value);
            % filenum = app.filenums;
            % cols = app.datacolumns;
            switch app.whichpeak
                case 'low peaks'
                    delete_high_and_low = 0;
                    disp('deleting low peaks')
                case 'high peaks'
                    delete_high_and_low = 1;
                    disp('deleting high peaks')
                case 'both'
                    delete_high_and_low = 2;
                    disp('deleting both high and low peaks')
            end
            % function [Data_BPM] = delete_all_peaks(Data_BPM, index, datacolumns, delete_high_and_low)
            % % 1 -> delete high peaks (default), % 0 (or anything below 1) -> delete low peaks,   % 2 (or anything > 1) -> delete high and low peaks
            [datbpm] = delete_all_peaks(datbpm,filenum,cols,delete_high_and_low); %
            % Update current bpm
            % function Data_BPM = update_Data_BPM_single_file(filenums, datacols, using_high_peaks,DataInfo, Data_BPM)
            if strcmp(app.whichpeak,'low peaks') % update with low peaks
                datbpm = update_Data_BPM_single_file(filenum, cols, 0,datinfo,datbpm);
            elseif strcmp(app.whichpeak,'high peaks') % update with high peaks
                datbpm = update_Data_BPM_single_file(filenum, cols, 1,datinfo,datbpm);
            else%elseif strcmp(app.whichpeak,'both') % check which high or low peak used
                datbpm = update_Data_BPM_single_file(filenum, cols, [],datinfo,datbpm);
            end
            assignin('base','Data_BPM',datbpm) % updating peaks to workspace
        end

        % Selection changed function: ChoosepeakstohandleButtonGroup
        function ChoosepeakstohandleButtonGroupSelectionChanged(app, event)
            app.whichpeak = app.ChoosepeakstohandleButtonGroup.SelectedObject.Text;
        end

        % Callback function
        function ChoosepeaksButtonGroupSizeChanged(app, event)
            position = app.ChoosepeakstohandleButtonGroup.Position;
            
        end

        % Button pushed function: delete_peaks_with_peaknumberButton
        function delete_peaks_with_peaknumberButtonPushed(app, event)
            peak_numbers = str2num(app.peaknumbersEditField.Value);
            % disp('deleting following peaks')
            datbpm = evalin('base','Data_BPM');
            datinfo = evalin('base','DataInfo');
            [filenums,cols,which_peaks_handled] = read_values_from_app(app);
            switch which_peaks_handled
                case 0
                    delete_high_and_low = 0;
                    disp('deleting low peaks')
                case 1
                    delete_high_and_low = 1;
                    disp('deleting high peaks')
                case 2
                    delete_high_and_low = 2;
                    disp('deleting both high and low peaks')
            end
            disp('Peak numbers to be deleted:')
            disp(app.peaknumbersEditField.Value)
            
            % function [Data_BPM] = delete_peaks_with_peaknumber(Data_BPM,file_index,datacolumns,peak_numbers_to_delete,high_or_low_peaks)
            % [Data_BPM] = delete_peaks_with_peaknumber(Data_BPM,file_index,datacolumns,peak_numbers_to_delete,high_or_low_peaks)
            % default: high_or_low_peaks = 0 --> deleting low peaks, 1 = deleting high peaks
            if delete_high_and_low < 2
                [datbpm] = delete_peaks_with_peaknumber(datbpm,filenums,cols,peak_numbers,delete_high_and_low); %
            else % if both --> first deleting low, then high
                [datbpm] = delete_peaks_with_peaknumber(datbpm,filenums,cols,peak_numbers,0);
                [datbpm] = delete_peaks_with_peaknumber(datbpm,filenums,cols,peak_numbers,1);
            end
            % assignin('base','Data_BPM',datbpm)
            % Update current bpm
            % function Data_BPM = update_Data_BPM_single_file(filenums, datacols, using_high_peaks,DataInfo, Data_BPM)
            if which_peaks_handled == 0 % update wit low peak
                datbpm = update_Data_BPM_single_file(filenums, cols, 0,datinfo,datbpm);
            elseif which_peaks_handled == 1 % update wit high peak
                datbpm = update_Data_BPM_single_file(filenums, cols, 1,datinfo,datbpm);
            elseif which_peaks_handled == 2 % check which high or low peak used
                datbpm = update_Data_BPM_single_file(filenums, cols, [],datinfo,datbpm);
            end
            assignin('base','Data_BPM',datbpm) % updating peaks to workspace
            assignin('base','DataInfo',datinfo) % updating DataInfo TODO: tarvitaanko?
            
            
        end

        % Button pushed function: delete_peaks_with_timeButton
        function delete_peaks_with_timeButtonPushed(app, event)
            time_range = str2num(app.timerangeEditField.Value);
            datbpm = evalin('base','Data_BPM');
            datinfo = evalin('base','DataInfo');
            [filenums,cols,which_peaks_handled] = read_values_from_app(app);
            switch which_peaks_handled
                case 0
                    delete_high_and_low = 0;
                    disp('deleting low peaks')
                case 1
                    delete_high_and_low = 1;
                    disp('deleting high peaks')
                case 2
                    delete_high_and_low = 2;
                    disp('deleting both high and low peaks')
            end
            disp('Peaks in the following time range will be deleted:')
            disp([min(time_range) max(time_range)])
            
            
            if delete_high_and_low < 2
                [datbpm] = delete_peaks_with_time(datbpm,filenums,cols,time_range,delete_high_and_low); %
            else % if both --> first deleting low, then high
                [datbpm] = delete_peaks_with_time(datbpm,filenums,cols,time_range,0);
                [datbpm] = delete_peaks_with_time(datbpm,filenums,cols,time_range,1);
            end
            % Update current bpm
            % function Data_BPM = update_Data_BPM_single_file(filenums, datacols, using_high_peaks,DataInfo, Data_BPM)
            if which_peaks_handled == 0 % update with low peaks
                datbpm = update_Data_BPM_single_file(filenums, cols, 0,datinfo,datbpm);
            elseif which_peaks_handled == 1 % update with high peaks
                datbpm = update_Data_BPM_single_file(filenums, cols, 1,datinfo,datbpm);
            elseif which_peaks_handled == 2 % check which high or low peak used
                datbpm = update_Data_BPM_single_file(filenums, cols, [],datinfo,datbpm);
            end
            assignin('base','Data_BPM',datbpm) % updating peaks to workspace
        end

        % Button pushed function: delete_peaks_belowButton
        function delete_peaks_belowButtonPushed(app, event)
            min_amplitude = str2num(app.minAmplitudeEditField.Value);
            datbpm = evalin('base','Data_BPM');
            datinfo = evalin('base','DataInfo');
            [filenums,cols,which_peaks_handled] = read_values_from_app(app);
            switch which_peaks_handled
                case 0
                    delete_high_and_low = 0;
                    disp('deleting low peaks')
                case 1
                    delete_high_and_low = 1;
                    disp('deleting high peaks')
                case 2
                    delete_high_and_low = 2;
                    disp('deleting both high and low peaks')
            end
            disp('Low amplitude peaks will be deleted, below:')
            disp([min_amplitude])
            
            if delete_high_and_low < 2
                [datbpm] = delete_peaks_below_value(datbpm,filenums,cols,min_amplitude,delete_high_and_low); %
            else % if both --> first deleting low, then high
                [datbpm] = delete_peaks_below_value(datbpm,filenums,cols,min_amplitude,0);
                [datbpm] = delete_peaks_below_value(datbpm,filenums,cols,min_amplitude,1);
            end
            % Update current bpm
            % function Data_BPM = update_Data_BPM_single_file(filenums, datacols, using_high_peaks,DataInfo, Data_BPM)
            if which_peaks_handled == 0 % update with low peaks
                datbpm = update_Data_BPM_single_file(filenums, cols, 0,datinfo,datbpm);
            elseif which_peaks_handled == 1 % update with high peaks
                datbpm = update_Data_BPM_single_file(filenums, cols, 1,datinfo,datbpm);
            elseif which_peaks_handled == 2 % check which high or low peak used
                datbpm = update_Data_BPM_single_file(filenums, cols, [],datinfo,datbpm);
            end
            assignin('base','Data_BPM',datbpm) % updating peaks to workspace
            
        end

        % Button pushed function: delete_peaks_aboveButton
        function delete_peaks_aboveButtonPushed(app, event)
            max_amplitude = str2num(app.maxAmplitudeEditField.Value);
            datbpm = evalin('base','Data_BPM');
            datinfo = evalin('base','DataInfo');
            [filenums,cols,which_peaks_handled] = read_values_from_app(app);
            switch which_peaks_handled
                case 0
                    delete_high_and_low = 0;
                    disp('deleting low peaks')
                case 1
                    delete_high_and_low = 1;
                    disp('deleting high peaks')
                case 2
                    delete_high_and_low = 2;
                    disp('deleting both high and low peaks')
            end
            disp('Too high amplitude peaks will be deleted, above:')
            disp([max_amplitude])
            
            if delete_high_and_low < 2
                [datbpm] = delete_peaks_above_value(datbpm,filenums,cols,max_amplitude,delete_high_and_low); %
            else % if both --> first deleting low, then high
                [datbpm] = delete_peaks_above_value(datbpm,filenums,cols,max_amplitude,0);
                [datbpm] = delete_peaks_above_value(datbpm,filenums,cols,max_amplitude,1);
            end
            % Update current bpm
            % function Data_BPM = update_Data_BPM_single_file(filenums, datacols, using_high_peaks,DataInfo, Data_BPM)
            if which_peaks_handled == 0 % update with low peaks
                datbpm = update_Data_BPM_single_file(filenums, cols, 0,datinfo,datbpm);
            elseif which_peaks_handled == 1 % update with high peaks
                datbpm = update_Data_BPM_single_file(filenums, cols, 1,datinfo,datbpm);
            elseif which_peaks_handled == 2 % check which high or low peak used
                datbpm = update_Data_BPM_single_file(filenums, cols, [],datinfo,datbpm);
            end
            assignin('base','Data_BPM',datbpm) % updating peaks to workspace
        end

        % Value changed function: MinPeakDistancemsEditField
        function MinPeakDistancemsEditFieldValueChanged(app, event)
            value = str2num(app.MinPeakDistancemsEditField.Value);
            app.maxBPMinpeakfind = value/1e3*60;
            app.MaxBPMEditField.Value = num2str(app.maxBPMinpeakfind);
        end

        % Value changed function: MaxBPMEditField
        function MaxBPMEditFieldValueChanged(app, event)
            app.maxBPMinpeakfind = str2num(app.MaxBPMEditField.Value);
            app.MinPeakDistancemsEditField.Value = num2str(60/app.maxBPMinpeakfind*1e3);
        end

        % Value changed function: MinPeakAmplitudeabsEditField
        function MinPeakAmplitudeabsEditFieldValueChanged(app, event)
            app.minAmptofind = str2num(app.MinPeakAmplitudeabsEditField.Value);
        end

        % Button pushed function: UpdatefilenumsfromworkspaceButton
        function UpdatefilenumsfromworkspaceButtonPushed(app, event)
            disp('Updating current filenum based on value on workspace')
            try
                current_filenum = evalin('base','current_filenum');
                disp(['Setting current_filenum = ',num2str(current_filenum), ' to filenum field'])
                app.filenums = current_filenum;
                app.filenumsEditField.Value = num2str(current_filenum);
                
            catch
                warning('No current_filenum variable found on base workspace! returning')
                return
            end
            
        end

        % Button pushed function: plot_next_in_filenum
        function plot_next_in_filenumPushed(app, event)
            next_plot_filenum_value = max(app.filenums)+1;
            last_file_index = evalin('base','DataInfo.files_amount');
            if next_plot_filenum_value > last_file_index
                warning('Last file index, cannot plot next')
            else
                app.filenumsEditField.Value = num2str(next_plot_filenum_value);
                filenumsEditFieldValueChanged(app, event)
                close all
                PlotDataButtonPushed(app, event)
            end
        end

        % Button pushed function: plot_every10th_signal
        function plot_every10th_signalButtonPushed(app, event)
            dat = evalin('base','Data');
            datinfo = evalin('base','DataInfo');
            plot_chosen_data_files(dat, datinfo)
            % function [] = plot_chosen_data_files(Data, DataInfo, filenumbers, datacolumns, helping_levels, MonitorNumber)
        end

        % Button pushed function: find_local_maximaButton
        function find_local_maximaButtonPushed(app, event)
            time_range = str2num(app.TimerangeEditField.Value);
            if any(isinf(time_range)) || isempty(time_range)
                warning('No time range given to find local maximum -> exiting')
                return
            end
            dat = evalin('base','Data');
            try
                datbpm = evalin('base','Data_BPM');
            catch
                datbpm = [];
                disp('no Data_BPM on workspace')
            end
            datinfo = evalin('base','DataInfo');
            
            [file,cols,which_peaks_handled] = read_values_from_app(app);
            % [Data_BPM] = add_peaks_with_time(Data, Data_BPM, DataInfo, file_index, datacolumns, time_range, high_or_low_peak)
            % Update current bpm:  function Data_BPM = update_Data_BPM_single_file(filenums, datacols, using_high_peaks,DataInfo, Data_BPM)
            switch which_peaks_handled
                case 0 % low peaks
                    disp('Finding minimum (low peak) between specific time')
                    datbpm = add_peak_with_time(dat, datbpm, datinfo, file, cols, time_range,-1);
                    datbpm = update_Data_BPM_single_file(file, cols, 0, datinfo, datbpm);
                case 1 % high peaks
                    disp('Finding maximum (high peak) between specific time')
                    datbpm = add_peak_with_time(dat, datbpm, datinfo, file, cols, time_range,1);
                    datbpm = update_Data_BPM_single_file(file, cols, 1, datinfo, datbpm);
                case 2 % both peaks
                    disp('Finding maximum (high peak) and minimum (low peak) between specific time')
                    % high
                    datbpm = add_peak_with_time(dat, datbpm, datinfo, file, cols, time_range,1);
                    % low
                    datbpm = add_peak_with_time(dat, datbpm, datinfo, file, cols, time_range,-1);
                    datbpm = update_Data_BPM_single_file(file, cols, [], datinfo, datbpm);
            end
            % Update current bpm
            assignin('base','Data_BPM',datbpm) % updating peaks to workspace
            
        end

        % Button pushed function: plot_random_file
        function plot_random_fileButtonPushed(app, event)
            
            dat = evalin('base','Data');
            try
                datbpm = evalin('base','Data_BPM');
            catch
                datbpm = [];
                disp('No Data_BPM')
            end
            how_many_plots = app.amount_of_random_files.Value;
            % filenum = unique(sort(randi(max(size(dat)),how_many_plots,1)))';
            if how_many_plots > max(size(dat))
                % if user input asked more plots than there is different data,
                % setting maximum number to data size
                how_many_plots = max(size(dat));
                % update "How many" field
                app.amount_of_random_files.Value = how_many_plots;
            end
            filenum = sort(randperm(max(size(dat)),how_many_plots));
            disp(['Plotting randomly ', num2str(length(filenum)), ' different data'])
            cols = 1:min(size(dat{filenum(1),1}.data));
            plotting_peak_numbers = app.IcludepeaknumbersinplotCheckBox.Value;
            plotting_data_columns_to_same = app.PlotdatacolumnstosameCheckBox.Value;
            hfigs = zeros(length(filenum),1);
            for kk = 1:length(filenum)
                hfigs(kk,1) = create_figure(app);
                if isempty(datbpm) % just raw data
                    PLOT_DataOnly_no_own_figure(dat,filenum(kk),cols,plotting_data_columns_to_same);
                else
                    PLOT_DataWithPeaks_no_own_fig(dat,datbpm,filenum(kk),cols,plotting_peak_numbers,plotting_data_columns_to_same)
                end
                zoom on
                %% OLD Version for a while
%                 if isempty(datbpm) % just raw data
%                     hf = PLOT_DataOnly(dat,filenum(kk),cols,plotting_data_columns_to_same);
%                     hfigs(kk,1) = hf;zoom on
%                 else
%                     hf = PLOT_DataWithPeaks(dat,datbpm,filenum(kk),cols,plotting_peak_numbers,plotting_data_columns_to_same);
%                     hfigs(kk,1) = hf;zoom on
%                 end
            end

            pause(0.2)
            for kk=length(hfigs):-1:1
                figure(hfigs(kk,1))
            end
        end

        % Button pushed function: 
        % check_peak_distances_from_edgesButton
        function check_peak_distances_from_edgesButtonPushed(app, event)
            warning('TODO')
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            disp('Saving only "small" variables: Excluding Data + other large variables that might take for a while)')
            savefolder = [app.SavingfolderLabel.Text];
            if strcmp(savefolder, 'Saving folder')
                savefolder = [];
            end
            newsavefolder = save_data_files(savefolder);
            % function save_data_files(savefolder, saving_DataPeaks_mean, saving_DataPeaks,saving_raw_data,...
            disp(['Saved in folder: ',newsavefolder])
            if isempty(savefolder)
                app.SavingfolderLabel.Text=newsavefolder;
            end
            
        end

        % Button pushed function: delete_first_peakButton
        function delete_first_peakButtonPushed(app, event)
            peak_numbers = 1;
            disp('deleting First peak')
            datbpm = evalin('base','Data_BPM');
            datinfo = evalin('base','DataInfo');
            [filenums,cols,which_peaks_handled] = read_values_from_app(app);
            switch which_peaks_handled
                case 0
                    delete_high_and_low = 0;
                    disp('deleting low peaks')
                case 1
                    delete_high_and_low = 1;
                    disp('deleting high peaks')
                case 2
                    delete_high_and_low = 2;
                    disp('deleting both high and low peaks')
            end
            disp('Peak numbers to be deleted:')
            disp(app.peaknumbersEditField.Value)
            
            % function [Data_BPM] = delete_peaks_with_peaknumber(Data_BPM,file_index,datacolumns,peak_numbers_to_delete,high_or_low_peaks)
            % [Data_BPM] = delete_peaks_with_peaknumber(Data_BPM,file_index,datacolumns,peak_numbers_to_delete,high_or_low_peaks)
            % default: high_or_low_peaks = 0 --> deleting low peaks, 1 = deleting high peaks
            if delete_high_and_low < 2
                [datbpm] = delete_peaks_with_peaknumber(datbpm,filenums,cols,peak_numbers,delete_high_and_low); %
            else % if both --> first deleting low, then high
                [datbpm] = delete_peaks_with_peaknumber(datbpm,filenums,cols,peak_numbers,0);
                [datbpm] = delete_peaks_with_peaknumber(datbpm,filenums,cols,peak_numbers,1);
            end
            % Update current bpm
            % function Data_BPM = update_Data_BPM_single_file(filenums, datacols, using_high_peaks,DataInfo, Data_BPM)
            if which_peaks_handled == 0 % update with low peaks
                datbpm = update_Data_BPM_single_file(filenums, cols, 0,datinfo,datbpm);
            elseif which_peaks_handled == 1 % update with high peaks
                datbpm = update_Data_BPM_single_file(filenums, cols, 1,datinfo,datbpm);
            elseif which_peaks_handled == 2 % check which high or low peaks used
                datbpm = update_Data_BPM_single_file(filenums, cols, [],datinfo,datbpm);
            end
            assignin('base','Data_BPM',datbpm) % updating peaks to workspace
        end

        % Button pushed function: delete_last_peakButton
        function delete_last_peakButtonPushed(app, event)
            disp('deleting LAST peak')
            datbpm = evalin('base','Data_BPM');
            datinfo = evalin('base','DataInfo');
            [filenums,cols,which_peaks_handled] = read_values_from_app(app);
            switch which_peaks_handled
                case 0
                    delete_high_and_low = 0;
                    disp('deleting low peaks')
                case 1
                    delete_high_and_low = 1;
                    disp('deleting high peaks')
                case 2
                    delete_high_and_low = 2;
                    disp('deleting both high and low peaks')
            end
            % getting each last peak index
            for file_index = filenums
                for datacol_index = cols
                    switch delete_high_and_low
                        case 0 % low peaks
                            try
                                peak_number = length(datbpm{file_index,1}.peak_values_low{datacol_index,1});
                            catch
                                peak_number = [];
                            end
                        case 1 % high peaks
                            try
                                peak_number = length(datbpm{file_index,1}.peak_values_high{datacol_index,1});
                            catch
                                peak_number = [];
                            end
                        case 2 % both
                            try
                                peak_number_low = length(datbpm{file_index,1}.peak_values_low{datacol_index,1});
                            catch
                                peak_number_low = [];
                            end
                            try
                                peak_number_high = length(datbpm{file_index,1}.peak_values_high{datacol_index,1});
                            catch
                                peak_number_high = [];
                            end
                    end
                    
                    disp(['Deleting peak#',num2str(peak_number),' from Data_BPM{',num2str(file_index),',1}(column=',num2str(datacol_index),')'])
                    % default: high_or_low_peaks = 0 --> deleting low peaks, 1 = deleting high peaks
                    if delete_high_and_low < 2
                        [datbpm] = delete_peaks_with_peaknumber(datbpm,file_index,datacol_index,peak_number,delete_high_and_low); %
                    else % if both --> first deleting low, then high
                        [datbpm] = delete_peaks_with_peaknumber(datbpm,file_index,datacol_index,peak_number_low,0);
                        [datbpm] = delete_peaks_with_peaknumber(datbpm,file_index,datacol_index,peak_number_high,1);
                    end
                end
            end
            % Update current bpm
            % function Data_BPM = update_Data_BPM_single_file(filenums, datacols, using_high_peaks,DataInfo, Data_BPM)
            if which_peaks_handled == 0 % update with low peaks
                datbpm = update_Data_BPM_single_file(filenums, cols, 0,datinfo,datbpm);
            elseif which_peaks_handled == 1 % update with high peaks
                datbpm = update_Data_BPM_single_file(filenums, cols, 1,datinfo,datbpm);
            elseif which_peaks_handled == 2 % check which high or low peaks used
                datbpm = update_Data_BPM_single_file(filenums, cols, [],datinfo,datbpm);
            end
            assignin('base','Data_BPM',datbpm) % updating peaks to workspace
        end

        % Button pushed function: clearallexceptButton
        function clearallexceptButtonPushed(app, event)
            vars = app.notremoveEditField.Value;
            disp(['clear all except following variables: ',10, vars])
            command_to_execute = ['clearvars -except ', vars];
            evalin('base', command_to_execute)
        end

        % Button pushed function: ClosethesefiguresButton
        function ClosethesefiguresButtonPushed(app, event)
            vars = str2num(app.FigurenumberstocloseEditField.Value);
            disp(['Closing following figures: ',num2str(vars)])
            command_to_execute = ['close([',num2str(vars),'])'];
            evalin('base',command_to_execute)
            
        end

        % Value changed function: DatacolumnsEditField
        function DatacolumnsEditFieldValueChanged(app, event)
            app.DatacolumnsEditField.Value = str2num(app.columnsEditField.Value);
        end

        % Value changing function: DatacolumnsEditField
        function DatacolumnsEditFieldValueChanging(app, event)
            changingValue = event.Value;
        end

        % Value changed function: filetype
        function filetypeValueChanged(app, event)
            value = app.filetype.Value.Text;
            disp(value)
        end

        % Selection changed function: DatatypeButtonGroup
        function DatatypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.DatatypeButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text,'Other')
                app.MEALayoutEditField.Visible = 'off';
                app.MEALayoutEditFieldLabel.Visible = 'off';
                app.MEAelectrodesEditField.Visible = 'off';
                app.MEAelectrodesEditFieldLabel.Visible = 'off';
                % datacolumns button on
                app.DatacolumnsEditField.Visible = 'on';
                app.DatacolumnsEditFieldLabel.Visible = 'on';
            else
                app.MEALayoutEditField.Visible = 'on';
                app.MEALayoutEditFieldLabel.Visible = 'on';
                app.MEAelectrodesEditField.Visible = 'on';
                app.MEAelectrodesEditFieldLabel.Visible = 'on';
                % datacolumns button off
                app.DatacolumnsEditField.Visible = 'off';
                app.DatacolumnsEditFieldLabel.Visible = 'off';
            end
        end

        % Button pushed function: CopyFiguretoclipboardButton
        function CopyFiguretoclipboardButtonPushed(app, event)
            cpfig
        end

        % Button pushed function: LoadmatfilesButton
        function LoadmatfilesButtonPushed(app, event)
            disp('Loading .mat files')
            loadfolder = [app.SavingfolderLabel.Text];
            if strcmp(loadfolder, 'Saving folder')
                loadfolder = [];
            end
            [folder_of_files, filename_list] = ... % , file_type, measurement_type] = ...
                list_files('.mat',loadfolder);
            [file_numbers_to_analyze] = choose_files(filename_list);
            for pp = 1:length(file_numbers_to_analyze)
                filename = [folder_of_files,filename_list{file_numbers_to_analyze(pp)}];
                load(filename);
                temp = whos('-file',filename);
                switch temp.name
                    case 'DataInfo'
                        assignin('base',temp.name, DataInfo);
                        try
                            datacols = 1:length(DataInfo.datacol_numbers);
                            disp('Datacolums updated.')
                        catch
                            datacols = 1;
                        end
                        app.columnsEditField.Value = num2str(datacols);
                        app.datacolumns = datacols;
                        app.columnsPeakHandlingEditField.Value = num2str(datacols);
                    case 'Data'
                        assignin('base',temp.name, Data);
                    case 'Data_BPM'
                        assignin('base',temp.name, Data_BPM);
                    case 'Data_BPM_summary'
                        assignin('base',temp.name, Data_BPM_summary);
                    case 'DataPeaks'
                        assignin('base',temp.name, DataPeaks);
                    case 'DataPeaks_mean'
                        assignin('base',temp.name, DataPeaks_mean);
                    case 'DataPeaks_summary'
                        assignin('base',temp.name, DataPeaks_summary);
                    case 'DataPeaks_o2'
                        assignin('base',temp.name, DataPeaks_o2);
                        
                end
            end
        end

        % Button pushed function: ListfilenamesoffilenumsButton
        function ListfilenamesoffilenumsButtonPushed(app, event)
            try
                datainfo = evalin('base','DataInfo');
            catch
                error('No DataInfo found')
            end
            file_indexes = app.filenums;
            disp([10,'%%%%% Chosen indexes are pointing to following files: %%%%%'])
            for file_index=1:length(file_indexes)
                disp([num2str(file_index),') File#',num2str(file_indexes(file_index)),...
                    ': ',datainfo.file_names{file_indexes(file_index),1}])
            end
        end

        % Selection changed function: SizeButtonGroup
        function SizeButtonGroupSelectionChanged(app, event)
            selectedButton = app.SizeButtonGroup.SelectedObject;

        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Analysis_Environment = uifigure;
            app.Analysis_Environment.Name = 'Analysis Environment';
            app.TabGroup = uitabgroup(app.Analysis_Environment);
            app.LoaddataTab = uitab(app.TabGroup);
            app.LoaddataTab.Title = 'Load data';
            app.ReadrawdataButton = uibutton(app.LoaddataTab, 'push');
            app.ReadrawdataButton.ButtonPushedFcn = createCallbackFcn(app, @ReadrawdataButtonPushed, true);
            app.ReadrawdataButton.Text = 'Read raw data';
            app.filetype = uidropdown(app.LoaddataTab);
            app.filetype.Items = {'.h5', '.abf', '.csv', '.txt', '.atf', '.mat', '.avi'};
            app.filetype.ValueChangedFcn = createCallbackFcn(app, @filetypeValueChanged, true);
            app.filetype.Value = '.h5';
            app.DatacolumnsEditFieldLabel = uilabel(app.LoaddataTab);
            app.DatacolumnsEditFieldLabel.Visible = 'off';
            app.DatacolumnsEditFieldLabel.Text = 'Datacolumns';
            app.DatacolumnsEditField = uieditfield(app.LoaddataTab, 'text');
            app.DatacolumnsEditField.ValueChangedFcn = createCallbackFcn(app, @DatacolumnsEditFieldValueChanged, true);
            app.DatacolumnsEditField.ValueChangingFcn = createCallbackFcn(app, @DatacolumnsEditFieldValueChanging, true);
            app.DatacolumnsEditField.Visible = 'off';
            app.experimentnameEditFieldLabel = uilabel(app.LoaddataTab);
            app.experimentnameEditFieldLabel.Text = 'experiment name';
            app.experimentnameEditField = uieditfield(app.LoaddataTab, 'text');
            app.measurementnameEditFieldLabel = uilabel(app.LoaddataTab);
            app.measurementnameEditFieldLabel.Text = 'measurement name';
            app.measurementnameEditField = uieditfield(app.LoaddataTab, 'text');
            app.measurementdateEditFieldLabel = uilabel(app.LoaddataTab);
            app.measurementdateEditFieldLabel.Text = 'measurement date';
            app.measurementdateEditField = uieditfield(app.LoaddataTab, 'text');
            app.FiletypeLabel = uilabel(app.LoaddataTab);
            app.FiletypeLabel.Text = 'Filetype';
            app.MEALayoutEditFieldLabel = uilabel(app.LoaddataTab);
            app.MEALayoutEditFieldLabel.Text = 'MEA Layout';
            app.MEALayoutEditField = uieditfield(app.LoaddataTab, 'text');
            app.DatatypeButtonGroup = uibuttongroup(app.LoaddataTab);
            app.DatatypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DatatypeButtonGroupSelectionChanged, true);
            app.DatatypeButtonGroup.Title = 'Data type';
            app.MEAButton = uiradiobutton(app.DatatypeButtonGroup);
            app.MEAButton.Text = 'MEA';
            app.MEAButton.Value = true;
            app.OtherButton = uiradiobutton(app.DatatypeButtonGroup);
            app.OtherButton.Text = 'Other';
            app.MEAelectrodesEditFieldLabel = uilabel(app.LoaddataTab);
            app.MEAelectrodesEditFieldLabel.Text = 'MEA electrodes';
            app.MEAelectrodesEditField = uieditfield(app.LoaddataTab, 'text');
            app.ProcessdatafilteringPanel = uipanel(app.LoaddataTab);
            app.ProcessdatafilteringPanel.Title = 'Process data: filtering';
            app.FilterdataButton = uibutton(app.ProcessdatafilteringPanel, 'push');
            app.FilterdataButton.Text = 'Filter data';
            app.PeakHandlingTab = uitab(app.TabGroup);
            app.PeakHandlingTab.Title = 'Peak Handling';
            app.ChoosepeakstohandleButtonGroup = uibuttongroup(app.PeakHandlingTab);
            app.ChoosepeakstohandleButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ChoosepeakstohandleButtonGroupSelectionChanged, true);
            app.ChoosepeakstohandleButtonGroup.Title = 'Choose peaks to handle';
            app.highpeaksButton = uiradiobutton(app.ChoosepeakstohandleButtonGroup);
            app.highpeaksButton.Text = 'high peaks';
            app.lowpeaksButton = uiradiobutton(app.ChoosepeakstohandleButtonGroup);
            app.lowpeaksButton.Text = 'low peaks';
            app.lowpeaksButton.Value = true;
            app.bothButton = uiradiobutton(app.ChoosepeakstohandleButtonGroup);
            app.bothButton.Text = 'both';
            app.filenumsPeakHandlingEditField_Label = uilabel(app.ChoosepeakstohandleButtonGroup);
            app.filenumsPeakHandlingEditField_Label.Text = 'filenums';
            app.filenumsPeakHandlingEditField = uieditfield(app.ChoosepeakstohandleButtonGroup, 'text');
            app.filenumsPeakHandlingEditField.Value = '1';
            app.columnsPeakHandlingEditField_Label = uilabel(app.ChoosepeakstohandleButtonGroup);
            app.columnsPeakHandlingEditField_Label.Text = 'columns';
            app.columnsPeakHandlingEditField = uieditfield(app.ChoosepeakstohandleButtonGroup, 'text');
            app.columnsPeakHandlingEditField.Value = '1';
            app.RulesandfindpeaksPanel = uipanel(app.PeakHandlingTab);
            app.RulesandfindpeaksPanel.Tooltip = {'default forward time = 0.3 sec if only one value given in Time range'};
            app.RulesandfindpeaksPanel.Title = 'Rules and findpeaks ';
            app.TimerangeEditFieldLabel = uilabel(app.RulesandfindpeaksPanel);
            app.TimerangeEditFieldLabel.Text = 'Time range';
            app.TimerangeEditField = uieditfield(app.RulesandfindpeaksPanel, 'text');
            app.find_local_maximaButton = uibutton(app.RulesandfindpeaksPanel, 'push');
            app.find_local_maximaButton.ButtonPushedFcn = createCallbackFcn(app, @find_local_maximaButtonPushed, true);
            app.find_local_maximaButton.Text = 'find_local_maxima';
            app.find_peaks_with_rulesButton = uibutton(app.RulesandfindpeaksPanel, 'push');
            app.find_peaks_with_rulesButton.ButtonPushedFcn = createCallbackFcn(app, @find_peaks_with_rulesButtonPushed, true);
            app.find_peaks_with_rulesButton.Text = 'find_peaks_with_rules';
            app.MaxBPMLabel = uilabel(app.RulesandfindpeaksPanel);
            app.MaxBPMLabel.Text = 'Max BPM';
            app.MaxBPMEditField = uieditfield(app.RulesandfindpeaksPanel, 'text');
            app.MaxBPMEditField.ValueChangedFcn = createCallbackFcn(app, @MaxBPMEditFieldValueChanged, true);
            app.MaxBPMEditField.Value = '60';
            app.MinPeakDistancemsLabel = uilabel(app.RulesandfindpeaksPanel);
            app.MinPeakDistancemsLabel.Text = 'Min Peak Distance (ms)';
            app.MinPeakDistancemsEditField = uieditfield(app.RulesandfindpeaksPanel, 'text');
            app.MinPeakDistancemsEditField.ValueChangedFcn = createCallbackFcn(app, @MinPeakDistancemsEditFieldValueChanged, true);
            app.MinPeakDistancemsEditField.Value = '1000';
            app.MinPeakAmplitudeabsLabel = uilabel(app.RulesandfindpeaksPanel);
            app.MinPeakAmplitudeabsLabel.Text = 'MinPeakAmplitude (abs)';
            app.MinPeakAmplitudeabsEditField = uieditfield(app.RulesandfindpeaksPanel, 'text');
            app.MinPeakAmplitudeabsEditField.ValueChangedFcn = createCallbackFcn(app, @MinPeakAmplitudeabsEditFieldValueChanged, true);
            app.MinPeakAmplitudeabsEditField.Value = '2.5e-5';
            app.DeletepeaksPanel = uipanel(app.PeakHandlingTab);
            app.DeletepeaksPanel.Title = 'Delete peaks';
            app.delete_all_peaksButton = uibutton(app.DeletepeaksPanel, 'push');
            app.delete_all_peaksButton.ButtonPushedFcn = createCallbackFcn(app, @delete_all_peaksButtonPushed, true);
            app.delete_all_peaksButton.Text = 'delete_all_peaks';
            app.delete_peaks_with_peaknumberButton = uibutton(app.DeletepeaksPanel, 'push');
            app.delete_peaks_with_peaknumberButton.ButtonPushedFcn = createCallbackFcn(app, @delete_peaks_with_peaknumberButtonPushed, true);
            app.delete_peaks_with_peaknumberButton.Text = 'delete_peaks_with_peaknumber';
            app.peaknumbersEditField = uieditfield(app.DeletepeaksPanel, 'text');
            app.peaknumbersEditField.Value = '1';
            app.peaknumbersEditField_Label = uilabel(app.DeletepeaksPanel);
            app.peaknumbersEditField_Label.Text = 'peaknumbers';
            app.delete_peaks_with_timeButton = uibutton(app.DeletepeaksPanel, 'push');
            app.delete_peaks_with_timeButton.ButtonPushedFcn = createCallbackFcn(app, @delete_peaks_with_timeButtonPushed, true);
            app.delete_peaks_with_timeButton.Text = 'delete_peaks_with_time';
            app.delete_peaks_with_time_Label = uilabel(app.DeletepeaksPanel);
            app.delete_peaks_with_time_Label.Text = 'timerange';
            app.timerangeEditField = uieditfield(app.DeletepeaksPanel, 'text');
            app.timerangeEditField.Value = '1';
            app.delete_peaks_belowButton = uibutton(app.DeletepeaksPanel, 'push');
            app.delete_peaks_belowButton.ButtonPushedFcn = createCallbackFcn(app, @delete_peaks_belowButtonPushed, true);
            app.delete_peaks_belowButton.Text = 'delete_peaks_below';
            app.delete_peaks_below_Label = uilabel(app.DeletepeaksPanel);
            app.delete_peaks_below_Label.Text = 'minvalue (abs)';
            app.minAmplitudeEditField = uieditfield(app.DeletepeaksPanel, 'text');
            app.minAmplitudeEditField.Value = '1';
            app.delete_peaks_aboveButton = uibutton(app.DeletepeaksPanel, 'push');
            app.delete_peaks_aboveButton.ButtonPushedFcn = createCallbackFcn(app, @delete_peaks_aboveButtonPushed, true);
            app.delete_peaks_aboveButton.Text = 'delete_peaks_above';
            app.delete_peaks_above_Label = uilabel(app.DeletepeaksPanel);
            app.delete_peaks_above_Label.Text = 'maxvalue (abs)';
            app.maxAmplitudeEditField = uieditfield(app.DeletepeaksPanel, 'text');
            app.maxAmplitudeEditField.Value = '1';
            app.delete_first_peakButton = uibutton(app.DeletepeaksPanel, 'push');
            app.delete_first_peakButton.ButtonPushedFcn = createCallbackFcn(app, @delete_first_peakButtonPushed, true);
            app.delete_first_peakButton.Text = 'delete_first_peak';
            app.delete_last_peakButton = uibutton(app.DeletepeaksPanel, 'push');
            app.delete_last_peakButton.ButtonPushedFcn = createCallbackFcn(app, @delete_last_peakButtonPushed, true);
            app.delete_last_peakButton.Text = 'delete_last_peak';
            app.DefinepeaksPanel = uipanel(app.PeakHandlingTab);
            app.DefinepeaksPanel.Title = 'Define peaks ';
            app.add_peaks_from_otherButton = uibutton(app.DefinepeaksPanel, 'push');
            app.add_peaks_from_otherButton.Enable = 'off';
            app.add_peaks_from_otherButton.Text = 'add_peaks_from_other';
            app.set_main_peaksButton = uibutton(app.DefinepeaksPanel, 'push');
            app.set_main_peaksButton.Enable = 'off';
            app.set_main_peaksButton.Text = 'set_main_peaks';
            app.define_other_peaks_from_mainButton = uibutton(app.DefinepeaksPanel, 'push');
            app.define_other_peaks_from_mainButton.Enable = 'off';
            app.define_other_peaks_from_mainButton.Text = 'define_other_peaks_from_main';
            app.CheckpeaksPanel = uipanel(app.PeakHandlingTab);
            app.CheckpeaksPanel.Title = 'Check peaks';
            app.check_peak_distances_from_edgesButton = uibutton(app.CheckpeaksPanel, 'push');
            app.check_peak_distances_from_edgesButton.ButtonPushedFcn = createCallbackFcn(app, @check_peak_distances_from_edgesButtonPushed, true);
            app.check_peak_distances_from_edgesButton.Enable = 'off';
            app.check_peak_distances_from_edgesButton.Text = 'check_peak_distances_from_edges';
            app.AddpeaksPanel = uipanel(app.PeakHandlingTab);
            app.AddpeaksPanel.Title = 'Add peaks';
            app.addpeaks_in_time_rangeButton = uibutton(app.AddpeaksPanel, 'push');
            app.addpeaks_in_time_rangeButton.Enable = 'off';
            app.addpeaks_in_time_rangeButton.Tooltip = {'tarvitaanko'};
            app.addpeaks_in_time_rangeButton.Text = 'add peaks_in_time_range';
            app.AnalysisTab = uitab(app.TabGroup);
            app.AnalysisTab.Title = 'Analysis';
            app.PoistolaariTab = uitab(app.TabGroup);
            app.PoistolaariTab.Title = 'Poistolaari';
            app.SaveData_BPMButton = uibutton(app.PoistolaariTab, 'push');
            app.SaveData_BPMButton.ButtonPushedFcn = createCallbackFcn(app, @SaveData_BPMButtonPushed, true);
            app.SaveData_BPMButton.Enable = 'off';
            app.SaveData_BPMButton.Text = 'Save Data_BPM';
            app.SaveBPM_summaryButton = uibutton(app.PoistolaariTab, 'push');
            app.SaveBPM_summaryButton.ButtonPushedFcn = createCallbackFcn(app, @SaveData_BPM_summaryButtonPushed, true);
            app.SaveBPM_summaryButton.Enable = 'off';
            app.SaveBPM_summaryButton.Text = 'Save BPM_summary';
            app.Save_closeall_plot_nextButton = uibutton(app.PoistolaariTab, 'push');
            app.Save_closeall_plot_nextButton.ButtonPushedFcn = createCallbackFcn(app, @Save_closeall_plot_nextButtonPushed, true);
            app.Save_closeall_plot_nextButton.Enable = 'off';
            app.Save_closeall_plot_nextButton.Text = 'Save_closeall_plot_next';
            app.SaveDataInfoButton = uibutton(app.PoistolaariTab, 'push');
            app.SaveDataInfoButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDataInfoButtonPushed, true);
            app.SaveDataInfoButton.Enable = 'off';
            app.SaveDataInfoButton.Text = 'Save DataInfo';
            app.TextArea_6 = uitextarea(app.PoistolaariTab);
            app.TextArea_6.Editable = 'off';
            app.TextArea_6.Value = {'Notice: Save Data might take for a while'};
            app.TextArea_7 = uitextarea(app.PoistolaariTab);
            app.TextArea_7.Editable = 'off';
            app.TextArea_7.Value = {'Clears unnecessary variables from workspace'; 'Variables given above are kept'};
            app.EditdataPanel = uipanel(app.PoistolaariTab);
            app.EditdataPanel.Title = 'Edit data';
            app.SignalTypeDropDown = uidropdown(app.EditdataPanel);
            app.SignalTypeDropDown.Items = {'normal_mea', 'low_mea', 'high_mea', 'none', 'normal_ca', 'normal_ap', 'normal_video', 'normal'};
            app.SignalTypeDropDown.Value = 'normal_mea';
            app.set_signal_typeButton = uibutton(app.EditdataPanel, 'push');
            app.set_signal_typeButton.Text = 'set_signal_type';
            app.turn_signalButton = uibutton(app.PoistolaariTab, 'push');
            app.turn_signalButton.Text = 'turn_signal';
            app.GeneralPanel = uipanel(app.Analysis_Environment);
            app.GeneralPanel.Title = 'General';
            app.ChoosedataforplottingPanel = uipanel(app.GeneralPanel);
            app.ChoosedataforplottingPanel.Title = 'Choose data for plotting';
            app.IcludepeaknumbersinplotCheckBox = uicheckbox(app.ChoosedataforplottingPanel);
            app.IcludepeaknumbersinplotCheckBox.Text = 'Iclude peak numbers in plot';
            app.IcludepeaknumbersinplotCheckBox.Value = true;
            app.PlotdatacolumnstosameCheckBox = uicheckbox(app.ChoosedataforplottingPanel);
            app.PlotdatacolumnstosameCheckBox.Text = 'Plot data columns to same';
            app.filenumsEditField_Label = uilabel(app.ChoosedataforplottingPanel);
            app.filenumsEditField_Label.Text = 'filenums';
            app.filenumsEditField = uieditfield(app.ChoosedataforplottingPanel, 'text');
            app.filenumsEditField.ValueChangedFcn = createCallbackFcn(app, @filenumsEditFieldValueChanged, true);
            app.filenumsEditField.ValueChangingFcn = createCallbackFcn(app, @filenumsEditFieldValueChanging, true);
            app.filenumsEditField.Value = '1';
            app.columnsEditField_Label = uilabel(app.ChoosedataforplottingPanel);
            app.columnsEditField_Label.Text = 'columns';
            app.columnsEditField = uieditfield(app.ChoosedataforplottingPanel, 'text');
            app.columnsEditField.ValueChangedFcn = createCallbackFcn(app, @columnsEditFieldValueChanged, true);
            app.columnsEditField.ValueChangingFcn = createCallbackFcn(app, @columnsEditFieldValueChanging, true);
            app.columnsEditField.Value = '1';
            app.PlotdatafilestosameCheckBox = uicheckbox(app.ChoosedataforplottingPanel);
            app.PlotdatafilestosameCheckBox.Text = 'Plot data files to same';
            app.SaveloadandclearPanel = uipanel(app.GeneralPanel);
            app.SaveloadandclearPanel.Title = 'Save, load, and clear';
            app.ChooseFolderButton = uibutton(app.SaveloadandclearPanel, 'push');
            app.ChooseFolderButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseFolderButtonPushed, true);
            app.ChooseFolderButton.Text = 'Choose Folder';
            app.SavingfolderLabel = uilabel(app.SaveloadandclearPanel);
            app.SavingfolderLabel.Text = 'Saving folder';
            app.SaveDataButton = uibutton(app.SaveloadandclearPanel, 'push');
            app.SaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDataButtonPushed, true);
            app.SaveDataButton.Tooltip = {'Notice: Save Data might take for a while'};
            app.SaveDataButton.Text = 'Save Data';
            app.TextArea_4 = uitextarea(app.SaveloadandclearPanel);
            app.TextArea_4.Editable = 'off';
            app.TextArea_4.Value = {'Do NOT save Data variable!'; 'Use Save Data button for that'};
            app.SaveButton = uibutton(app.SaveloadandclearPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Tooltip = {'This does NOT SAVE Data!'; 'Use "Save Data" button for that'};
            app.SaveButton.Text = 'Save';
            app.clearallexceptButton = uibutton(app.SaveloadandclearPanel, 'push');
            app.clearallexceptButton.ButtonPushedFcn = createCallbackFcn(app, @clearallexceptButtonPushed, true);
            app.clearallexceptButton.Tooltip = {'Clears unnecessary variables from workspace'; 'Variables given above are kept'};
            app.clearallexceptButton.Text = 'clear all except';
            app.notremoveEditField = uieditfield(app.SaveloadandclearPanel, 'text');
            app.notremoveEditField.Value = 'Data DataInfo Data_BPM Data_BPM_summary Data_o2 DataPeaks DataPeaks_mean DataPeaks_summary';
            app.LoadmatfilesButton = uibutton(app.SaveloadandclearPanel, 'push');
            app.LoadmatfilesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadmatfilesButtonPushed, true);
            app.LoadmatfilesButton.Text = 'Load .mat file(s)';
            app.PlotPanel = uipanel(app.GeneralPanel);
            app.PlotPanel.Title = 'Plot';
            app.PlotRAWButton = uibutton(app.PlotPanel, 'push');
            app.PlotRAWButton.ButtonPushedFcn = createCallbackFcn(app, @PlotRAWButtonPushed, true);
            app.PlotRAWButton.Text = {'Plot '; 'RAW'};
            app.PlotDataButton = uibutton(app.PlotPanel, 'push');
            app.PlotDataButton.ButtonPushedFcn = createCallbackFcn(app, @PlotDataButtonPushed, true);
            app.PlotDataButton.Text = {'Plot '; 'Data'};
            app.CloseallButton = uibutton(app.PlotPanel, 'push');
            app.CloseallButton.ButtonPushedFcn = createCallbackFcn(app, @CloseallButtonPushed, true);
            app.CloseallButton.Text = {'Close '; 'all'};
            app.PlotsignalupsidedownButton = uibutton(app.PlotPanel, 'push');
            app.PlotsignalupsidedownButton.ButtonPushedFcn = createCallbackFcn(app, @PlotsignalupsidedownButtonPushed, true);
            app.PlotsignalupsidedownButton.Text = {'Plot signal '; 'upside down'};
            app.plot_every10th_signal = uibutton(app.PlotPanel, 'push');
            app.plot_every10th_signal.ButtonPushedFcn = createCallbackFcn(app, @plot_every10th_signalButtonPushed, true);
            app.plot_every10th_signal.Text = 'plot_every10th_signal';
            app.plot_random_file = uibutton(app.PlotPanel, 'push');
            app.plot_random_file.ButtonPushedFcn = createCallbackFcn(app, @plot_random_fileButtonPushed, true);
            app.plot_random_file.Text = 'Plot random files';
            app.amount_of_random_files = uieditfield(app.PlotPanel, 'numeric');
            app.amount_of_random_files.Value = 1;
            app.AmountofplotsLabel = uilabel(app.PlotPanel);
            app.AmountofplotsLabel.Text = 'Amount of plots';
            app.plot_next_in_filenum = uibutton(app.PlotPanel, 'push');
            app.plot_next_in_filenum.ButtonPushedFcn = createCallbackFcn(app, @plot_next_in_filenumPushed, true);
            app.plot_next_in_filenum.Tooltip = {'Close all & plot next filenums += 1'};
            app.plot_next_in_filenum.Text = 'Plot next plot';
            app.ClosethesefiguresButton = uibutton(app.PlotPanel, 'push');
            app.ClosethesefiguresButton.ButtonPushedFcn = createCallbackFcn(app, @ClosethesefiguresButtonPushed, true);
            app.ClosethesefiguresButton.Tooltip = {'Closes those figure numbers that are given in FigNumbers field above'};
            app.ClosethesefiguresButton.Text = {'Close '; 'these '; 'figures'};
            app.SizeButtonGroup = uibuttongroup(app.PlotPanel);
            app.SizeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SizeButtonGroupSelectionChanged, true);
            app.SizeButtonGroup.Title = 'Size';
            app.DefaultsizeButton = uiradiobutton(app.SizeButtonGroup);
            app.DefaultsizeButton.Text = 'Default size';
            app.FullscreenButton = uiradiobutton(app.SizeButtonGroup);
            app.FullscreenButton.Text = 'Full screen';
            app.FullscreenButton.Value = true;
            app.HalfscreenButton = uiradiobutton(app.SizeButtonGroup);
            app.HalfscreenButton.Text = 'Half screen';
            app.FigurenumberstocloseLabel = uilabel(app.PlotPanel);
            app.FigurenumberstocloseLabel.Text = 'Figure numbers to close';
            app.FigurenumberstocloseEditField = uieditfield(app.PlotPanel, 'text');
            app.CopyFiguretoclipboardButton = uibutton(app.PlotPanel, 'push');
            app.CopyFiguretoclipboardButton.ButtonPushedFcn = createCallbackFcn(app, @CopyFiguretoclipboardButtonPushed, true);
            app.CopyFiguretoclipboardButton.Tooltip = {'Copies current figure to clipboard'};
            app.CopyFiguretoclipboardButton.Text = {'Copy Figure '; 'to clipboard'};
            app.UpdatefilenumsfromworkspaceButton = uibutton(app.GeneralPanel, 'push');
            app.UpdatefilenumsfromworkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @UpdatefilenumsfromworkspaceButtonPushed, true);
            app.UpdatefilenumsfromworkspaceButton.Text = 'Update filenums from workspace';
            app.PlotButton = uibutton(app.GeneralPanel, 'push');
            app.PlotButton.Enable = 'off';
            app.PlotButton.Text = 'Plot';
            app.RawDataCheckBox = uicheckbox(app.GeneralPanel);
            app.RawDataCheckBox.Enable = 'off';
            app.RawDataCheckBox.Text = 'Raw Data';
            app.MeanDataPeakCheckBox = uicheckbox(app.GeneralPanel);
            app.MeanDataPeakCheckBox.Enable = 'off';
            app.MeanDataPeakCheckBox.Text = 'Mean DataPeak';
            app.MeanDataPeakCheckBox.Value = true;
            app.everyDataPeaksCheckBox = uicheckbox(app.GeneralPanel);
            app.everyDataPeaksCheckBox.Enable = 'off';
            app.everyDataPeaksCheckBox.Text = 'everyDataPeaks';
            app.ListfilenamesoffilenumsButton = uibutton(app.GeneralPanel, 'push');
            app.ListfilenamesoffilenumsButton.ButtonPushedFcn = createCallbackFcn(app, @ListfilenamesoffilenumsButtonPushed, true);
            app.ListfilenamesoffilenumsButton.Tooltip = {'Lists chosen filenums of MATLAB workspace'};
            app.ListfilenamesoffilenumsButton.Text = {'List file names'; '(of filenums)'};
            app.PrintsfilenamesonworkspaceLabel = uilabel(app.GeneralPanel);
            app.PrintsfilenamesonworkspaceLabel.Text = {'Prints filenames '; 'on workspace'};
        end
    end

    methods (Access = public)

        % Construct app
        function app = Analysis_environment

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Analysis_Environment)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Analysis_Environment)
        end
    end
end